classdef defocus_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FocallengthmmEditFieldLabel     matlab.ui.control.Label
        FocallengthmmEditField          matlab.ui.control.NumericEditField
        DiameteroflensmmEditFieldLabel  matlab.ui.control.Label
        DiameteroflensmmEditField       matlab.ui.control.NumericEditField
        WavelengthmmEditFieldLabel      matlab.ui.control.Label
        WavelengthmmEditField           matlab.ui.control.NumericEditField
        NumberofpixelsEditFieldLabel    matlab.ui.control.Label
        NumberofpixelsEditField         matlab.ui.control.NumericEditField
        LinearDefocusmmEditFieldLabel   matlab.ui.control.Label
        LinearDefocusmmEditField        matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        plotButton                      matlab.ui.control.Button
        PhasemaskDropDownLabel          matlab.ui.control.Label
        PhasemaskDropDown               matlab.ui.control.DropDown
        UIAxes_2                        matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            %initialize variables
            f = app.FocallengthmmEditField.Value; %focal length
            D = app.DiameteroflensmmEditField.Value; %diameter of lens
            wavelength = app.WavelengthmmEditField.Value; %wavelength of light
            pixels = app.NumberofpixelsEditField.Value; %pixels on detector
            dz = app.LinearDefocusmmEditField.Value; %linear defocus
            NA = D/(2*f); %numerical aperture in image space
            pixelSize = D/pixels; %size of one pixel in mm(<= 1/(2f_max))
            kMax = 2*pi/(pixelSize); %k = 2*pi/(pixel*pixelSize), therefore kmax is for the case when pixel=1
            kNA = 2*pi*NA/wavelength; %diameter of pupil in k-space
            dk = 2*pi/(pixels*pixelSize); %spacing between pixels in frequency space
            
            %create k space matrices and x,y vectors
            kx = linspace((-kMax - dk)/2, (kMax + dk)/2, pixels); %x spatial frequency
            ky = linspace((-kMax - dk)/2, (kMax + dk)/2, pixels); %y spatial frequency
            [KX,KY] = meshgrid(kx,ky); %create spatial frequency matrices  
            aperture = KX.^2 + KY.^2 <= kNA^2; %why???
            phase = 1i*2*pi*ones(pixels, pixels); %constant phase of lens
            pupil = aperture.*exp(phase); %pupil function is phase component times amplitude component
            x = linspace(-((pixels-1)/2)*pixelSize,((pixels-1)/2)*pixelSize,pixels); %x coordinates
            y = linspace(-(pixels/2)*pixelSize,(pixels/2)*pixelSize,pixels); %y coordinates
            
            %plot PSF
            phasemasks = {.00003*(KX.^3 +KY.^3), 30*(cos(pi*KY/kNA) + 0.5*sin(pi*KX/kNA)), 0, atan2(KY,KX)};
            phasemask = phasemasks{str2num(app.PhasemaskDropDown.Value)};
            defocusKernel = exp(1i*sqrt((2*pi/wavelength)^2 - KX.^2 - KY.^2)*dz);
            Pupil = pupil.*defocusKernel.*exp(1i*phasemask);
            N = size(Pupil, 1);
            defocusPSF = abs(ifftshift(ifft2(ifftshift(Pupil))) * (N * dk)^2);
            imagesc(app.UIAxes,x,y,defocusPSF);
            xlim(app.UIAxes,[-2.5 2.5])
            ylim(app.UIAxes,[-2.5 2.5])
            imagesc(app.UIAxes_2,kx,ky,phasemask.*aperture);
        end

        % Value changed function: PhasemaskDropDown
        function PhasemaskDropDownValueChanged(app, event)
            value = app.PhasemaskDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FocallengthmmEditFieldLabel = uilabel(app.UIFigure);
            app.FocallengthmmEditFieldLabel.Text = 'Focal length(mm)';
            app.FocallengthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.FocallengthmmEditField.Value = 75;
            app.DiameteroflensmmEditFieldLabel = uilabel(app.UIFigure);
            app.DiameteroflensmmEditFieldLabel.Text = 'Diameter of lens(mm)';
            app.DiameteroflensmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DiameteroflensmmEditField.Value = 25.4;
            app.WavelengthmmEditFieldLabel = uilabel(app.UIFigure);
            app.WavelengthmmEditFieldLabel.Text = 'Wavelength(mm)';
            app.WavelengthmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.WavelengthmmEditField.Value = 0.0068;
            app.NumberofpixelsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofpixelsEditFieldLabel.Text = 'Number of pixels';
            app.NumberofpixelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofpixelsEditField.Value = 3001;
            app.LinearDefocusmmEditFieldLabel = uilabel(app.UIFigure);
            app.LinearDefocusmmEditFieldLabel.Text = 'Linear Defocus(mm)';
            app.LinearDefocusmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PSF')
            xlabel(app.UIAxes, 'x [mm]')
            ylabel(app.UIAxes, 'y [mm]')
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.PhasemaskDropDownLabel = uilabel(app.UIFigure);
            app.PhasemaskDropDownLabel.Text = 'Phase mask';
            app.PhasemaskDropDown = uidropdown(app.UIFigure);
            app.PhasemaskDropDown.Items = {'Cubic Phase: a(X^3 + Y^3)', 'Double Airy: a(cos(pi*Y/k) + 0.5*sin(pi*X/k))', 'none', 'Phase Ramp: atan(KY/KX)'};
            app.PhasemaskDropDown.ItemsData = {'1', '2', '3', '4'};
            app.PhasemaskDropDown.ValueChangedFcn = createCallbackFcn(app, @PhasemaskDropDownValueChanged, true);
            app.PhasemaskDropDown.Value = '3';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Phase mask')
            xlabel(app.UIAxes_2, 'x [mm]')
            ylabel(app.UIAxes_2, 'y [mm]')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = defocus_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end